classdef OkulaAlimSistemi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OkulaAlmKararSistemiUIFigure    matlab.ui.Figure
        OgrTelefonNumaraEditField       matlab.ui.control.EditField
        TelefonNumaraLabel              matlab.ui.control.Label
        OgrTCEditField                  matlab.ui.control.EditField
        TCLabel                         matlab.ui.control.Label
        ResminiziSecinizButton          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        OgrAdSoyadEditField             matlab.ui.control.EditField
        AdSoyadEditFieldLabel           matlab.ui.control.Label
        KaydetveGrntleButton            matlab.ui.control.Button
        GrupProje12ButtonGroup          matlab.ui.container.ButtonGroup
        HiyapmamButton                  matlab.ui.control.RadioButton
        Nadirenyaparm2Button            matlab.ui.control.RadioButton
        Skskyapyorum1Button             matlab.ui.control.RadioButton
        Snavstresi11ButtonGroup         matlab.ui.container.ButtonGroup
        Endielenirimvepaniklerim4Button  matlab.ui.control.RadioButton
        Yardmisterim3Button             matlab.ui.control.RadioButton
        Birsremolaveririm2Button        matlab.ui.control.RadioButton
        Planyaparmveadmadmilerlerim1Button  matlab.ui.control.RadioButton
        Kisiselhedef10ButtonGroup       matlab.ui.container.ButtonGroup
        Dier5Button                     matlab.ui.control.RadioButton
        Kiiselgeliim4Button             matlab.ui.control.RadioButton
        Toplumhizmeti3Button            matlab.ui.control.RadioButton
        Sporveyasanatbaarlar2Button     matlab.ui.control.RadioButton
        Akademikbaar1Button             matlab.ui.control.RadioButton
        OgrenmeYontemi9ButtonGroup      matlab.ui.container.ButtonGroup
        UygulamaPratik3Button           matlab.ui.control.RadioButton
        Grsel2Button                    matlab.ui.control.RadioButton
        DinleyerekButton                matlab.ui.control.RadioButton
        ArkadasIliski8ButtonGroup       matlab.ui.container.ButtonGroup
        okiyi3Button                    matlab.ui.control.RadioButton
        Ortalama2Button                 matlab.ui.control.RadioButton
        iyi1Button2                     matlab.ui.control.RadioButton
        BursuAlma7ButtonGroup           matlab.ui.container.ButtonGroup
        Hayr2Button                     matlab.ui.control.RadioButton
        EvetButton_2                    matlab.ui.control.RadioButton
        Teknolojikullanmi13ButtonGroup  matlab.ui.container.ButtonGroup
        iyi3Button_3                    matlab.ui.control.RadioButton
        Orta2Button_2                   matlab.ui.control.RadioButton
        KtButton_2                      matlab.ui.control.RadioButton
        sanatBransOdul6ButtonGroup      matlab.ui.container.ButtonGroup
        YokButton_2                     matlab.ui.control.RadioButton
        Var1Button_2                    matlab.ui.control.RadioButton
        Yabancdil5ButtonGroup           matlab.ui.container.ButtonGroup
        iyi3Button                      matlab.ui.control.RadioButton
        Orta2Button                     matlab.ui.control.RadioButton
        KtButton                        matlab.ui.control.RadioButton
        bransBasari4ButtonGroup         matlab.ui.container.ButtonGroup
        EitArlk3Button                  matlab.ui.control.RadioButton
        Szel2Button                     matlab.ui.control.RadioButton
        SaysalButton                    matlab.ui.control.RadioButton
        SporLisans14ButtonGroup         matlab.ui.container.ButtonGroup
        Hayr2Button_2                   matlab.ui.control.RadioButton
        Evet1Button_3                   matlab.ui.control.RadioButton
        Aileningelirdurumu3ButtonGroup  matlab.ui.container.ButtonGroup
        dahafazla4Button                matlab.ui.control.RadioButton
        Button_25                       matlab.ui.control.RadioButton
        Button_17                       matlab.ui.control.RadioButton
        Button_0                        matlab.ui.control.RadioButton
        Diplomabasarpuani2ButtonGroup   matlab.ui.container.ButtonGroup
        Button_90100                    matlab.ui.control.RadioButton
        Button_7090                     matlab.ui.control.RadioButton
        Button_5070                     matlab.ui.control.RadioButton
        Button_050                      matlab.ui.control.RadioButton
        OkulaAlmKararSistemiLabel       matlab.ui.control.Label
        CinsiyetinizNedir1ButtonGroup   matlab.ui.container.ButtonGroup
        ErkekButton                     matlab.ui.control.RadioButton
        KadnButton                      matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    
        end

        % Selection changed function: CinsiyetinizNedir1ButtonGroup
        function CinsiyetinizNedir1ButtonGroupSelectionChanged(app, event)
           
        end

        % Button pushed function: ResminiziSecinizButton
        function ResminiziSecinizButtonPushed(app, event)
     % Resim seçimini kontrol et
[filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp;*.gif', 'Resim Dosyaları (*.jpg, *.png, *.bmp, *.gif)'; '*.*', 'Tüm Dosyalar (*.*)'}, 'Resim Seç');

% Kullanıcı resim seçerse devam et
if ~isequal(filename, 0)
    % Dosya yolu ve adını birleştir
    resimYolu = fullfile(pathname, filename);

    % Bir miktar bekleme ekle
    pause(0.1);

    % Seçilen resmi görüntü bileşenine ata
    app.Image.ImageSource = resimYolu;

    % Görüntüyü güncelle ve pencereyi ön plana al
    drawnow;
    figure(app.OkulaAlmKararSistemiUIFigure);
else
    % Kullanıcı resim seçmezse mesaj göster
    msgbox('Resim seçilmedi.', 'Bilgi', 'modal');
end

        end

        % Button pushed function: KaydetveGrntleButton
        function KaydetveGrntleButtonPushed(app, event)

            puan=0;
            ogrenci=struct('OgrTC',app.OgrTCEditField.Value, 'AdSoyad',app.OgrAdSoyadEditField.Value, ...
             'OgrFoto' ,app.Image.ImageSource, 'OgrTelelfonNumarası', app.OgrTelefonNumaraEditField.Value, ...
             'Cinsiyet', 0, 'DiplomaPuan' ,0 ,'AileGelir' ,0 ,'Brans' ,0 ,'YabanciDil' ,0 ,'OdulBasari' ,0 , ...
             'BursDurum' ,0 ,'ArkadasIliski' ,0 ,'OgrenmeYontemi' ,0 ,'KisiselHedef' ,0 , ...
             'SinavStres' ,0 ,'GrupProje' ,0 ,'TeknolojiKullanimi' ,0 ,'SporLisans' ,0 ,'ToplamPuan' ,0 );

            OgrenciBilgi=struct('OgrTC',app.OgrTCEditField.Value, 'AdSoyad',app.OgrAdSoyadEditField.Value, ...
             'OgrFoto' ,app.Image.ImageSource, 'OgrTelelfonNumarası', app.OgrTelefonNumaraEditField.Value, ...
             'Cinsiyet', '', 'DiplomaPuan' ,'' ,'AileGelir' ,'' ,'Brans' ,'' ,'YabanciDil' ,'' ,'OdulBasari' ,'' , ...
             'BursDurum' ,'' ,'ArkadasIliski' ,'' ,'OgrenmeYontemi' ,'' ,'KisiselHedef' ,'' , ...
             'SinavStres' ,'' ,'GrupProje' ,'' ,'TeknolojiKullanimi' ,'' ,'SporLisans' ,'' ,'ToplamPuan' ,0);

            %ogrenci TC
            if(app.OgrTCEditField.Value=="")
                msgbox('Ogrenci TC girişi yapmalısınız!','Hata','error','modal');
                return;
            end

            %ogrenci Adsoyad
            if(app.OgrAdSoyadEditField.Value=="")
                msgbox('Ogrenci AdSoyad girişi yapmalısınız!','Hata','error','modal');
                return;
            end

            %ogrenci Tel No
            if(app.OgrTelefonNumaraEditField.Value=="")
                msgbox('Telefon numarası girişi yapmalısınız!','Hata','error','modal');
                return;
            end

            %cinsiyet
            if(app.KadnButton.Value)
                puan=puan+1;
                ogrenci.Cinsiyet=1;
                OgrenciBilgi.Cinsiyet=app.KadnButton.Text;
            elseif(app.ErkekButton.Value)
                puan=puan+1;
                ogrenci.Cinsiyet=1;
                OgrenciBilgi.Cinsiyet=app.ErkekButton.Text;
            else
                 msgbox('Cinsiyet seçimi yapmalısınız.', 'Hata', 'error', 'modal');
                 return;
            end

           %diploma puanı
            if(app.Button_050.Value)
                puan=puan+1;
                ogrenci.DiplomaPuan=1;
                OgrenciBilgi.DiplomaPuan=app.Button_050.Text;
            elseif(app.Button_5070.Value)
                puan=puan+2;
                ogrenci.DiplomaPuan=2;
                OgrenciBilgi.DiplomaPuan=app.Button_5070.Text;
            elseif(app.Button_7090.Value)
                puan=puan+3;
                ogrenci.DiplomaPuan=3;
                OgrenciBilgi.DiplomaPuan=app.Button_7090.Text;

            elseif(app.Button_90100.Value)
                puan=puan+4;
                ogrenci.DiplomaPuan=4;
                OgrenciBilgi.DiplomaPuan=app.Button_90100.Text;

            else 
                msgbox('Diploma puanını seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end

            %Aile gelir durumu
            if(app.Button_0.Value)
                puan=puan+4;
                ogrenci.AileGelir=4;
                OgrenciBilgi.AileGelir=app.Button_0.Text;
            elseif(app.Button_17.Value)
                puan=puan+3;
                ogrenci.AileGelir=3;
                OgrenciBilgi.AileGelir=app.Button_17.Text;
            elseif(app.Button_25.Value)
                puan=puan+2;
                ogrenci.AileGelir=2;
                OgrenciBilgi.AileGelir=app.Button_25.Text;
            elseif(app.dahafazla4Button.Value)
                puan=puan+1;
                ogrenci.AileGelir=1;
                OgrenciBilgi.AileGelir=app.dahafazla4Button.Text;
           else 
                msgbox('Aile Gelir Durumunuzu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %Brans secımı
            if(app.SaysalButton.Value)
                puan=puan+3;
                ogrenci.Brans=3;
                OgrenciBilgi.Brans=app.SaysalButton.Text;
            elseif(app.EitArlk3Button.Value)
                puan=puan+2;
                ogrenci.Brans=2;
                OgrenciBilgi.Brans=app.EitArlk3Button.Text;
            elseif(app.Szel2Button.Value)
                puan=puan+1;
                ogrenci.Brans=1;
                OgrenciBilgi.Brans=app.Szel2Button.Text;
            else 
                msgbox('Başarılı olduğunuz Branşı seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %yabancıdil
            if(app.KtButton.Value)
                puan=puan+1;
                ogrenci.YabanciDil=1;
                OgrenciBilgi.YabanciDil=app.KtButton.Text;
            elseif(app.Orta2Button.Value)
                puan=puan+2;
                ogrenci.YabanciDil=2;
                OgrenciBilgi.YabanciDil=app.Orta2Button.Text;
            elseif(app.iyi3Button.Value)
                puan=puan+3;
                ogrenci.YabanciDil=3;
                OgrenciBilgi.YabanciDil=app.iyi3Button.Text;
            else 
                msgbox('Yabancı Dil seviyenizi seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 

            %odul basarı
            if(app.YokButton_2.Value)
                puan=puan+1;
                ogrenci.OdulBasari=1;
                OgrenciBilgi.OdulBasari=app.YokButton_2.Text;
            elseif(app.Var1Button_2.Value)
                puan=puan+2;
                ogrenci.OdulBasari=2;
                OgrenciBilgi.OdulBasari=app.Var1Button_2.Text;
            else 
                msgbox('odul basarınızı seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
            end 
   
           %BursDurum
           if(app.EvetButton_2.Value)
               puan=puan+2;
               ogrenci.BursDurum=2; 
               OgrenciBilgi.BursDurum=app.EvetButton_2.Text;
           elseif(app.Hayr2Button.Value)
               puan=puan+1;
               ogrenci.BursDurum=1; 
               OgrenciBilgi.BursDurum=app.Hayr2Button.Text;
           else
               msgbox('Burs durumunuzu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
           end 

           %Arkadasİliski
           if(app.iyi1Button2.Value)
               puan=puan+1;
               ogrenci.ArkadasIliski=1;
               OgrenciBilgi.ArkadasIliski=app.iyi1Button2.Text;
           elseif(app.Ortalama2Button.Value)
                puan=puan+2;
               ogrenci.ArkadasIliski=2;
               OgrenciBilgi.ArkadasIliski=app.Ortalama2Button.Text;
           elseif(app.okiyi3Button.Value)
               puan=puan+3;
               ogrenci.ArkadasIliski=3;
               OgrenciBilgi.ArkadasIliski=app.okiyi3Button.Text;
           else
                msgbox('Arkadaşlık ilişki durumunuzu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
           end

           %ogrenme yontemı
           if(app.DinleyerekButton.Value)
               puan=puan+1;
               ogrenci.OgrenmeYontemi=1;
               OgrenciBilgi.OgrenmeYontemi=app.DinleyerekButton.Text;
           elseif(app.Grsel2Button.Value)
               puan=puan+2;
               ogrenci.OgrenmeYontemi=2;
               OgrenciBilgi.OgrenmeYontemi=app.Grsel2Button.Text;
           elseif(app.UygulamaPratik3Button.Value)
               puan=puan+3;
               ogrenci.OgrenmeYontemi=3;
               OgrenciBilgi.OgrenmeYontemi=app.UygulamaPratik3Button.Text;
           else   
               msgbox(' en iyi ogrenme yöntemini seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
           end

           %Kişisel Hedef
             if(app.Akademikbaar1Button.Value)
               puan=puan+5;
               ogrenci.KisiselHedef=5;
               OgrenciBilgi.KisiselHedef=app.Akademikbaar1Button.Text;
             elseif(app.Sporveyasanatbaarlar2Button.Value)
                 puan=puan+3;
                 ogrenci.KisiselHedef=3;
                 OgrenciBilgi.KisiselHedef=app.Sporveyasanatbaarlar2Button.Text;
            elseif(app.Toplumhizmeti3Button.Value)
                 puan=puan+2;
                 ogrenci.KisiselHedef=2;
                 OgrenciBilgi.KisiselHedef=app.Toplumhizmeti3Button.Text;
            elseif(app.Kiiselgeliim4Button.Value)
                 puan=puan+4;
                 ogrenci.KisiselHedef=4;
                 OgrenciBilgi.KisiselHedef=app.Kiiselgeliim4Button.Text;
            elseif(app.Dier5Button.Value)
                 puan=puan+1;
                 ogrenci.KisiselHedef=1;
                 OgrenciBilgi.KisiselHedef=app.Dier5Button.Text;
             else
                  msgbox('Kişisel hedefinizi seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %sınav Stresi
             if(app.Planyaparmveadmadmilerlerim1Button.Value)
                 puan=puan+4;
                 ogrenci.SinavStres=4;
                 OgrenciBilgi.SinavStres=app.Planyaparmveadmadmilerlerim1Button.Text;
             elseif(app.Birsremolaveririm2Button.Value)
                 puan=puan+3;
                 ogrenci.SinavStres=3;
                 OgrenciBilgi.SinavStres=app.Birsremolaveririm2Button.Text;
             elseif(app.Yardmisterim3Button.Value)
                 puan=puan+2;
                 ogrenci.SinavStres=2;
                 OgrenciBilgi.SinavStres=app.Yardmisterim3Button.Text;
             elseif(app.Endielenirimvepaniklerim4Button.Value)
                 puan=puan+1;
                 ogrenci.SinavStres=1;
                 OgrenciBilgi.SinavStres=app.Endielenirimvepaniklerim4Button.Text;
             else
                 msgbox('Sınav Stresiyle sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %grup proje calısma
             if(app.Skskyapyorum1Button.Value)
                 puan=puan+3;
                 ogrenci.GrupProje=3;
                 OgrenciBilgi.GrupProje=app.Skskyapyorum1Button.Text;
             elseif(app.Nadirenyaparm2Button.Value)
                 puan=puan+2;
                 ogrenci.GrupProje=2;
                 OgrenciBilgi.GrupProje=app.Nadirenyaparm2Button.Text;
             elseif(app.HiyapmamButton.Value)
                 puan=puan+1;
                 ogrenci.GrupProje=1;
                 OgrenciBilgi.GrupProje=app.HiyapmamButton.Text;
             else
                 msgbox('grup proje sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end
             
             %teknolojı sorusu
             if(app.KtButton_2.Value)
                puan=puan+1;
                ogrenci.TeknolojiKullanimi=1;
                OgrenciBilgi.TeknolojiKullanimi=app.KtButton_2.Text;
             elseif(app.Orta2Button_2.Value)
                puan=puan+2;
                ogrenci.TeknolojiKullanimi=2;
                OgrenciBilgi.TeknolojiKullanimi=app.Orta2Button_2.Text;

             elseif(app.iyi3Button_3.Value)
                 puan=puan+3;
                 ogrenci.TeknolojiKullanimi=3;
                 OgrenciBilgi.TeknolojiKullanimi=app.iyi3Button_3.Text;
             else
                  msgbox('teknoloji sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end

             %spor lisansı 
             if(app.Evet1Button_3.Value)
                 puan=puan+2;
                 ogrenci.SporLisans=2;
                 OgrenciBilgi.SporLisans=app.Evet1Button_3.Text;
             elseif(app.Hayr2Button_2.Value)
                 puan=puan+1;
                 ogrenci.SporLisans=1;
                 OgrenciBilgi.SporLisans=app.Hayr2Button_2.Text;
             else
                  msgbox('spor lisansı sorusunu seçmelisiniz!.', 'Hata', 'error', 'modal');
                 return;
             end
             %Resim secımı:
             if(app.Image.ImageSource == "")
               msgbox('Resim seçimi yapmalısınız.', 'Hata', 'error', 'modal');
               return;
             end
             %kriterlerin önem agırlıgını içeren matris
             onemMatrisi = [
                 1; %Cinsiyet
                 5; %Diploma Puanı
                 4; %Aile Gelir
                 3; %Brans Secimi
                 3; %YabancıDil
                 2; %Odul Basari
                 5; %Burs Durumu
                 2; %Arkadaslık İlişkisi
                 4; %Ogrenme Yontemı
                 3; %Kişisel Hedef
                 3; %Sınav stresi
                 4; %Grup Projesi
                 2; %teknoloji kullanımı
                 5; %Spor Lisans      
             ];
             %ogrencının secımlerını içeren vektor
             secimVektoru = [
                 ogrenci.Cinsiyet;
                 ogrenci.DiplomaPuan;
                 ogrenci.AileGelir;
                 ogrenci.Brans;
                 ogrenci.YabanciDil;
                 ogrenci.OdulBasari;
                 ogrenci.BursDurum;
                 ogrenci.ArkadasIliski;
                 ogrenci.OgrenmeYontemi;
                 ogrenci.KisiselHedef;
                 ogrenci.SinavStres;
                 ogrenci.GrupProje;
                 ogrenci.TeknolojiKullanimi;
                 ogrenci.SporLisans;             
             ];
             %KARAR DESTEK ICIN PUAN HESAPLAMA
             puan=sum(onemMatrisi .* secimVektoru);

             %toplam puanı ogrencı struct ekle
             ogrenci.ToplamPuan=puan;
             OgrenciBilgi.ToplamPuan=puan;

             ogrenci.OgrFoto =app.Image.ImageSource;

             %VERİTABANI İŞLEMLERİ
             conn=database('KararDestekOkulAlımSistemi','','');
            komut="INSERT INTO [dbo].[Tbl_Ogrencii] ([OgrTC],[AdSoyad],[OgrFoto],[OgrTelelfonNumarası],[Cinsiyet],[DiplomaPuan],[AileGelir],[Brans],[YabanciDil],[OdulBasari],[BursDurum],[Arkadasİliski],[OgrenmeYontemi],[KisiselHedef],[SinavStres],[GrupProje],[TeknolojiKullanimi] ,[SporLisans],[ToplamPuan])  VALUES    " + ...
             "('"+app.OgrTCEditField.Value+"' ,'"+app.OgrAdSoyadEditField.Value+"', '"+app.Image.ImageSource+"','"+app.OgrTelefonNumaraEditField.Value+"' ,'"+OgrenciBilgi.Cinsiyet+"','"+OgrenciBilgi.DiplomaPuan+"' ,'"+OgrenciBilgi.AileGelir+"','"+OgrenciBilgi.Brans+"','"+OgrenciBilgi.YabanciDil+"','"+OgrenciBilgi.OdulBasari+"'," + ...
             "'"+ OgrenciBilgi.BursDurum+"','"+ OgrenciBilgi.ArkadasIliski+"','"+OgrenciBilgi.OgrenmeYontemi+"','"+OgrenciBilgi.KisiselHedef+"','"+OgrenciBilgi.SinavStres+"','"+OgrenciBilgi.GrupProje+"','"+ OgrenciBilgi.TeknolojiKullanimi+"','"+OgrenciBilgi.SporLisans+"','"+ num2str(OgrenciBilgi.ToplamPuan)+"')";
           exec(conn, komut);
           close(conn)
           msgbox(sprintf('Değerlendirme Puanınız: %d', puan), 'Değerlendirme Sonucu', 'modal');
%          aday.toplamPuan = puan
          app.ogrenciler{end+1} = ogrenci;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OkulaAlmKararSistemiUIFigure = uifigure('Visible', 'off');
            app.OkulaAlmKararSistemiUIFigure.Name = 'OkulaAlımKararSistemi';
            app.CinsiyetinizNedir1ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.CinsiyetinizNedir1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CinsiyetinizNedir1ButtonGroupSelectionChanged, true);
            app.CinsiyetinizNedir1ButtonGroup.Title = 'Cinsiyetiniz Nedir?';
            app.KadnButton = uiradiobutton(app.CinsiyetinizNedir1ButtonGroup);
            app.KadnButton.Text = 'Kadın';
            app.KadnButton.Value = true;
            app.ErkekButton = uiradiobutton(app.CinsiyetinizNedir1ButtonGroup);
            app.ErkekButton.Text = 'Erkek';
            app.OkulaAlmKararSistemiLabel = uilabel(app.OkulaAlmKararSistemiUIFigure);
            app.OkulaAlmKararSistemiLabel.Text = 'Okula Alım Karar Sistemi';
            app.Diplomabasarpuani2ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.Diplomabasarpuani2ButtonGroup.Title = 'Bir önceki diploma basarı puanınız ?';
            app.Button_050 = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.Button_050.Text = '0-50';
            app.Button_050.Value = true;
            app.Button_5070 = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.Button_5070.Text = '50-70';
            app.Button_7090 = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.Button_7090.Text = '70-90';
            app.Button_90100 = uiradiobutton(app.Diplomabasarpuani2ButtonGroup);
            app.Button_90100.Text = '90-100';
            app.Aileningelirdurumu3ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.Aileningelirdurumu3ButtonGroup.Title = 'Ailenin gelir durumu?';
            app.Button_0 = uiradiobutton(app.Aileningelirdurumu3ButtonGroup);
            app.Button_0.Text = '0-17000';
            app.Button_0.Value = true;
            app.Button_17 = uiradiobutton(app.Aileningelirdurumu3ButtonGroup);
            app.Button_17.Text = '17000-25000';
            app.Button_25 = uiradiobutton(app.Aileningelirdurumu3ButtonGroup);
            app.Button_25.Text = '25000-50000';
            app.dahafazla4Button = uiradiobutton(app.Aileningelirdurumu3ButtonGroup);
            app.dahafazla4Button.Text = 'daha fazla';
            app.SporLisans14ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.SporLisans14ButtonGroup.Title = 'Spor Lisansın var mı?';
            app.Evet1Button_3 = uiradiobutton(app.SporLisans14ButtonGroup);
            app.Evet1Button_3.Text = 'Evet';
            app.Evet1Button_3.Value = true;
            app.Hayr2Button_2 = uiradiobutton(app.SporLisans14ButtonGroup);
            app.Hayr2Button_2.Text = 'Hayır';
            app.bransBasari4ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.bransBasari4ButtonGroup.Title = 'Hangi branşta başarılısınız ?';
            app.SaysalButton = uiradiobutton(app.bransBasari4ButtonGroup);
            app.SaysalButton.Text = 'Sayısal';
            app.SaysalButton.Value = true;
            app.Szel2Button = uiradiobutton(app.bransBasari4ButtonGroup);
            app.Szel2Button.Text = 'Sözel';
            app.EitArlk3Button = uiradiobutton(app.bransBasari4ButtonGroup);
            app.EitArlk3Button.Text = 'Eşit Ağırlık';
            app.Yabancdil5ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.Yabancdil5ButtonGroup.Title = 'Yabancı dil düzeyin nasıl?';
            app.KtButton = uiradiobutton(app.Yabancdil5ButtonGroup);
            app.KtButton.Text = 'Kötü';
            app.KtButton.Value = true;
            app.Orta2Button = uiradiobutton(app.Yabancdil5ButtonGroup);
            app.Orta2Button.Text = 'Orta';
            app.iyi3Button = uiradiobutton(app.Yabancdil5ButtonGroup);
            app.iyi3Button.Text = 'İyi';
            app.sanatBransOdul6ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.sanatBransOdul6ButtonGroup.Title = 'sanat branşlarında ödülü var mı ?';
            app.Var1Button_2 = uiradiobutton(app.sanatBransOdul6ButtonGroup);
            app.Var1Button_2.Text = 'Var';
            app.Var1Button_2.Value = true;
            app.YokButton_2 = uiradiobutton(app.sanatBransOdul6ButtonGroup);
            app.YokButton_2.Text = 'Yok';
            app.Teknolojikullanmi13ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.Teknolojikullanmi13ButtonGroup.Title = 'Teknoloji kullanımı ile aran nasıl?';
            app.KtButton_2 = uiradiobutton(app.Teknolojikullanmi13ButtonGroup);
            app.KtButton_2.Text = 'Kötü';
            app.KtButton_2.Value = true;
            app.Orta2Button_2 = uiradiobutton(app.Teknolojikullanmi13ButtonGroup);
            app.Orta2Button_2.Text = 'Orta';
            app.iyi3Button_3 = uiradiobutton(app.Teknolojikullanmi13ButtonGroup);
            app.iyi3Button_3.Text = 'İyi';
            app.BursuAlma7ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.BursuAlma7ButtonGroup.Title = 'Daha önce herhangi bursu aldınızmı?';
            app.EvetButton_2 = uiradiobutton(app.BursuAlma7ButtonGroup);
            app.EvetButton_2.Text = 'Evet';
            app.EvetButton_2.Value = true;
            app.Hayr2Button = uiradiobutton(app.BursuAlma7ButtonGroup);
            app.Hayr2Button.Text = 'Hayır';
            app.ArkadasIliski8ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.ArkadasIliski8ButtonGroup.Title = 'Sınıf arkadaşlarınızla olan ilişkileriniz nasıldır?';
            app.iyi1Button2 = uiradiobutton(app.ArkadasIliski8ButtonGroup);
            app.iyi1Button2.Text = 'İyi';
            app.iyi1Button2.Value = true;
            app.Ortalama2Button = uiradiobutton(app.ArkadasIliski8ButtonGroup);
            app.Ortalama2Button.Text = ' Ortalama';
            app.okiyi3Button = uiradiobutton(app.ArkadasIliski8ButtonGroup);
            app.okiyi3Button.Text = ' Çok iyi';
            app.OgrenmeYontemi9ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.OgrenmeYontemi9ButtonGroup.Title = 'En iyi öğrenme yönteminiz nedir?';
            app.DinleyerekButton = uiradiobutton(app.OgrenmeYontemi9ButtonGroup);
            app.DinleyerekButton.Text = ' Dinleyerek';
            app.DinleyerekButton.Value = true;
            app.Grsel2Button = uiradiobutton(app.OgrenmeYontemi9ButtonGroup);
            app.Grsel2Button.Text = ' Görsel';
            app.UygulamaPratik3Button = uiradiobutton(app.OgrenmeYontemi9ButtonGroup);
            app.UygulamaPratik3Button.Text = ' Uygulama/Pratik';
            app.Kisiselhedef10ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.Kisiselhedef10ButtonGroup.Title = ' Kişisel hedefleriniz nelerdir?';
            app.Akademikbaar1Button = uiradiobutton(app.Kisiselhedef10ButtonGroup);
            app.Akademikbaar1Button.Text = ' Akademik başarı';
            app.Akademikbaar1Button.Value = true;
            app.Sporveyasanatbaarlar2Button = uiradiobutton(app.Kisiselhedef10ButtonGroup);
            app.Sporveyasanatbaarlar2Button.Text = 'Spor veya sanat başarıları';
            app.Toplumhizmeti3Button = uiradiobutton(app.Kisiselhedef10ButtonGroup);
            app.Toplumhizmeti3Button.Text = 'Toplum hizmeti';
            app.Kiiselgeliim4Button = uiradiobutton(app.Kisiselhedef10ButtonGroup);
            app.Kiiselgeliim4Button.Text = 'Kişisel gelişim';
            app.Dier5Button = uiradiobutton(app.Kisiselhedef10ButtonGroup);
            app.Dier5Button.Text = ' Diğer';
            app.Snavstresi11ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.Snavstresi11ButtonGroup.Title = 'Sınav stresi ile başa çıkma stratejiniz var?';
            app.Planyaparmveadmadmilerlerim1Button = uiradiobutton(app.Snavstresi11ButtonGroup);
            app.Planyaparmveadmadmilerlerim1Button.Text = 'Plan yaparım ve adım adım ilerlerim.';
            app.Planyaparmveadmadmilerlerim1Button.Value = true;
            app.Birsremolaveririm2Button = uiradiobutton(app.Snavstresi11ButtonGroup);
            app.Birsremolaveririm2Button.Text = ' Bir süre mola veririm.';
            app.Yardmisterim3Button = uiradiobutton(app.Snavstresi11ButtonGroup);
            app.Yardmisterim3Button.Text = 'Yardım isterim.';
            app.Endielenirimvepaniklerim4Button = uiradiobutton(app.Snavstresi11ButtonGroup);
            app.Endielenirimvepaniklerim4Button.Text = 'Endişelenirim ve paniklerim.';
            app.GrupProje12ButtonGroup = uibuttongroup(app.OkulaAlmKararSistemiUIFigure);
            app.GrupProje12ButtonGroup.Title = 'Grup projelerinde çalışmayı seviyor musunuz?';
            app.Skskyapyorum1Button = uiradiobutton(app.GrupProje12ButtonGroup);
            app.Skskyapyorum1Button.Text = 'Sık sık yapıyorum.';
            app.Skskyapyorum1Button.Value = true;
            app.Nadirenyaparm2Button = uiradiobutton(app.GrupProje12ButtonGroup);
            app.Nadirenyaparm2Button.Text = 'Nadiren yaparım.';
            app.HiyapmamButton = uiradiobutton(app.GrupProje12ButtonGroup);
            app.HiyapmamButton.Text = {'Hiç yapmam.'; ''};
            app.KaydetveGrntleButton = uibutton(app.OkulaAlmKararSistemiUIFigure, 'push');
            app.KaydetveGrntleButton.ButtonPushedFcn = createCallbackFcn(app, @KaydetveGrntleButtonPushed, true);
            app.KaydetveGrntleButton.Icon = fullfile(pathToMLAPP, 'indir.png');
            app.KaydetveGrntleButton.Text = 'Kaydet ve Görüntüle';
            app.AdSoyadEditFieldLabel = uilabel(app.OkulaAlmKararSistemiUIFigure);
            app.AdSoyadEditFieldLabel.Text = 'Ad Soyad';
            app.OgrAdSoyadEditField = uieditfield(app.OkulaAlmKararSistemiUIFigure, 'text');
            app.Image = uiimage(app.OkulaAlmKararSistemiUIFigure);
            app.ResminiziSecinizButton = uibutton(app.OkulaAlmKararSistemiUIFigure, 'push');
            app.ResminiziSecinizButton.ButtonPushedFcn = createCallbackFcn(app, @ResminiziSecinizButtonPushed, true);
            app.ResminiziSecinizButton.Text = 'Resminizi Seçiniz';
            app.TCLabel = uilabel(app.OkulaAlmKararSistemiUIFigure);
            app.TCLabel.Text = 'TC';
            app.OgrTCEditField = uieditfield(app.OkulaAlmKararSistemiUIFigure, 'text');
            app.TelefonNumaraLabel = uilabel(app.OkulaAlmKararSistemiUIFigure);
            app.TelefonNumaraLabel.Text = 'Telefon Numara';
            app.OgrTelefonNumaraEditField = uieditfield(app.OkulaAlmKararSistemiUIFigure, 'text');
            % Show the figure after all components are created
            app.OkulaAlmKararSistemiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OkulaAlimSistemi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OkulaAlmKararSistemiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OkulaAlmKararSistemiUIFigure)
        end
    end
end